classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        TabGroup2         matlab.ui.container.TabGroup
        Tab_1             matlab.ui.container.Tab
        FixedLabel_1      matlab.ui.control.Label
        Slider_1          matlab.ui.control.Slider
        FixedDropDown_1   matlab.ui.control.DropDown
        Tab_2             matlab.ui.container.Tab
        FixedLabel_2      matlab.ui.control.Label
        Slider_2          matlab.ui.control.Slider
        FixedDropDown_2   matlab.ui.control.DropDown
        XDropDownLabel    matlab.ui.control.Label
        ZDropDownLabel_3  matlab.ui.control.Label
        YDropDownLabel    matlab.ui.control.Label
        FixedValuesLabel  matlab.ui.control.Label
        ZDropDown         matlab.ui.control.DropDown
        YDropDown         matlab.ui.control.DropDown
        BrowseButton      matlab.ui.control.Button
        XDropDown         matlab.ui.control.DropDown
        AxicesLabel       matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        TotalTableTab     matlab.ui.container.Tab
        UITotalTable      matlab.ui.control.Table
        FilteredTableTab  matlab.ui.container.Tab
        UITable           matlab.ui.control.Table
        DiagramTab        matlab.ui.container.Tab
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        T table;

    end
    
    methods (Access = public)
        
        function UpdateTable(app)  
            try
                 % show selected headers in app
                 app.UITable.ColumnName={app.XDropDown.Value,...
                     app.YDropDown.Value,...
                     app.ZDropDown.Value};
                 % show selected columns in app
    
                 indices = ones(height(app.T),2);
    
                 for i=1:2
                     if(app.(sprintf('FixedDropDown_%d',i)).Value ~= "none")
                        indices(:,i) = ...
                            app.T.(app.(sprintf('FixedDropDown_%d',i)).Value)...
                            ==str2double(app.(sprintf('FixedLabel_%d',i)).Text);
                     end      
                 end
    
                 index=all(indices,2);
    
                 app.UITable.Data=app.T(index,app.UITable.ColumnName);
            catch
                return
            end
        end

        function LoadTable(app)       
             % show selected headers in app
             app.UITotalTable.ColumnName=app.T.Properties.VariableNames;
             % show selected columns in app
             app.UITotalTable.Data=app.T;
        end

        function UpdatePlot(app)
            try
                x=table2array(app.UITable.Data(:,1));
                y=table2array(app.UITable.Data(:,2));
                z=table2array(app.UITable.Data(:,3));
    
                [xUnq,~,xIdx]=unique(x);
                [yUnq,~,yIdx]=unique(y);
                zMat=nan(numel(yUnq), numel(xUnq));
                zIdx=sub2ind(size(zMat),yIdx, xIdx);
                zMat(zIdx)=z;
                contourf(app.UIAxes,...
                    xUnq, yUnq, zMat, 'ShowText', 'on', 'LineWidth',1);
                xlabel(app.UIAxes,app.XDropDown.Value);
                ylabel(app.UIAxes,app.YDropDown.Value);
                title(app.UIAxes,'Contour');
            catch
                return;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Read data from csv
             [fileName,pathAddress] = uigetfile("*.csv");

             if(fileName==0)
                 return;
             end
             fullAddress = append(pathAddress, fileName);
             app.T = readtable(fullAddress);

            % fix table headers
             headers = app.T.Properties.VariableNames;
             app.XDropDown.Items = headers;
             app.YDropDown.Items = headers;
             app.ZDropDown.Items = headers;
             for i=1:2
                app.(sprintf('FixedDropDown_%d',i)).Items = ["none",headers];
             end

             % Initialize table
             app.LoadTable();
        end

        % Value changed function: XDropDown, YDropDown, ZDropDown
        function XDropDownValueChanged(app, event)
            app.UpdateTable();
            app.UpdatePlot();
            
        end

        % Button down function: DiagramTab
        function DiagramTabButtonDown(app, event)
            app.UpdatePlot();
        end

        % Value changed function: FixedDropDown_1, FixedDropDown_2
        function FixedDropDown_1ValueChanged(app, event)
            try
                i = str2double(event.Source.Tag);
                if app.(sprintf('FixedDropDown_%d',i)).Value=="none"
                    app.(sprintf('Slider_%d',i)).Limits=[0,1];
                    app.(sprintf('Slider_%d',i)).MajorTicks=0:0.1:1;
                    app.(sprintf('Slider_%d',i)).MajorTickLabels=cellstr(num2str((0:0.1:1)'));
                    app.(sprintf('FixedLabel_%d',i)).Text = num2str(0);
                    app.(sprintf('Slider_%d',i)).Value = 0;
                    app.UpdateTable();
                    app.UpdatePlot();
                    return;
                end

                column = table2array(app.T(:,app.(sprintf('FixedDropDown_%d',i)).Value));
                UnqVals=unique(column);
                UnqVals=sort(UnqVals);
                UnqVals=UnqVals';
                
                if(length(UnqVals)==1)
                    app.(sprintf('Slider_%d',i)).Limits=[UnqVals(1); UnqVals(end)+1];
                else     
                    app.(sprintf('Slider_%d',i)).Limits=[UnqVals(1); UnqVals(end)];
                end
    
                if(length(UnqVals)<=10)
                    app.(sprintf('Slider_%d',i)).MajorTicks=UnqVals;
                    app.(sprintf('Slider_%d',i)).MajorTickLabels=cellstr(num2str(UnqVals'));
                else
%                     app.(sprintf('Slider_%d',i)).MajorTicks=linspace(UnqVals(1),UnqVals(end),10);
%                     app.(sprintf('Slider_%d',i)).MajorTickLabels=cellstr(num2str(linspace(UnqVals(1),UnqVals(end),10)'));
                    app.(sprintf('Slider_%d',i)).MajorTicks=UnqVals;

                    a = cellstr(num2str([UnqVals(1); UnqVals(end)]));
                    b = cellstr(repmat(' ',length(UnqVals)-2,1));
                    c = [a(1);b;a(end)];
                    app.(sprintf('Slider_%d',i)).MajorTickLabels=c;
%                     app.(sprintf('Slider_%d',i)).MajorTickLabels=...
%                         cellstr(num2str([UnqVals(1);'';'';'';'';'';UnqVals(end)]));
                end 

                value = UnqVals(1);
                app.(sprintf('FixedLabel_%d',i)).Text = num2str(value);
                app.(sprintf('Slider_%d',i)).Value = value;
                app.UpdateTable();
                app.UpdatePlot();
            catch
                return
            end
        end

        % Value changed function: Slider_1, Slider_2
        function Slider_1ValueChanged(app, event)
            i = str2double(event.Source.Tag);
            value = app.(sprintf('Slider_%d',i)).Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
            app.(sprintf('FixedLabel_%d',i)).Text = num2str(value);
            app.(sprintf('Slider_%d',i)).Value=value;
            app.UpdateTable();
            app.UpdatePlot();

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {643, 643};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AxicesLabel = uilabel(app.LeftPanel);
            app.AxicesLabel.Text = 'Axices';
            app.XDropDown = uidropdown(app.LeftPanel);
            app.XDropDown.Items = {};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = {};
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.YDropDown = uidropdown(app.LeftPanel);
            app.YDropDown.Items = {};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.YDropDown.Value = {};
            app.ZDropDown = uidropdown(app.LeftPanel);
            app.ZDropDown.Items = {};
            app.ZDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.ZDropDown.Value = {};
            app.FixedValuesLabel = uilabel(app.LeftPanel);
            app.FixedValuesLabel.Text = 'Fixed Values';
            app.YDropDownLabel = uilabel(app.LeftPanel);
            app.YDropDownLabel.Text = 'Y';
            app.ZDropDownLabel_3 = uilabel(app.LeftPanel);
            app.ZDropDownLabel_3.Text = 'Z';
            app.XDropDownLabel = uilabel(app.LeftPanel);
            app.XDropDownLabel.Text = 'X';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.Tab_1 = uitab(app.TabGroup2);
            app.Tab_1.Title = '#1';
            app.FixedDropDown_1 = uidropdown(app.Tab_1);
            app.FixedDropDown_1.Items = {};
            app.FixedDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FixedDropDown_1ValueChanged, true);
            app.FixedDropDown_1.Tag = '1';
            app.FixedDropDown_1.Value = {};
            app.Slider_1 = uislider(app.Tab_1);
            app.Slider_1.Limits = [0 1];
            app.Slider_1.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_1.Tag = '1';
            app.FixedLabel_1 = uilabel(app.Tab_1);
            app.FixedLabel_1.Tag = '1';
            app.FixedLabel_1.Text = '0';
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '#2';
            app.Tab_2.Tag = '2';
            app.FixedDropDown_2 = uidropdown(app.Tab_2);
            app.FixedDropDown_2.Items = {};
            app.FixedDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FixedDropDown_1ValueChanged, true);
            app.FixedDropDown_2.Tag = '2';
            app.FixedDropDown_2.Value = {};
            app.Slider_2 = uislider(app.Tab_2);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Tag = '2';
            app.FixedLabel_2 = uilabel(app.Tab_2);
            app.FixedLabel_2.Tag = '2';
            app.FixedLabel_2.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TotalTableTab = uitab(app.TabGroup);
            app.TotalTableTab.Title = 'Total Table';
            app.UITotalTable = uitable(app.TotalTableTab);
            app.UITotalTable.ColumnName = '';
            app.UITotalTable.RowName = {};
            app.FilteredTableTab = uitab(app.TabGroup);
            app.FilteredTableTab.Title = 'Filtered Table';
            app.UITable = uitable(app.FilteredTableTab);
            app.UITable.ColumnName = {'# 1'; '# 2'; '# 3'};
            app.UITable.RowName = {};
            app.DiagramTab = uitab(app.TabGroup);
            app.DiagramTab.Title = 'Diagram';
            app.DiagramTab.ButtonDownFcn = createCallbackFcn(app, @DiagramTabButtonDown, true);
            app.UIAxes = uiaxes(app.DiagramTab);
            title(app.UIAxes, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end